classdef BTL_Robot_2112447_PUMA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        InverseKinematicPanel  matlab.ui.container.Panel
        theta3EditField_inv    matlab.ui.control.NumericEditField
        XEditField_2Label_4    matlab.ui.control.Label
        theta2EditField_inv    matlab.ui.control.NumericEditField
        XEditField_2Label_3    matlab.ui.control.Label
        theta1EditField_inv    matlab.ui.control.NumericEditField
        XEditField_2Label_2    matlab.ui.control.Label
        InverseButton          matlab.ui.control.Button
        ZEditField_2Label      matlab.ui.control.Label
        ZEditField_inv         matlab.ui.control.NumericEditField
        YEditField_inv         matlab.ui.control.NumericEditField
        YEditField_2Label      matlab.ui.control.Label
        XEditField_inv         matlab.ui.control.NumericEditField
        XEditField_2Label      matlab.ui.control.Label
        ResetButton            matlab.ui.control.Button
        RFDisplayPanel         matlab.ui.container.Panel
        RF3CheckBox            matlab.ui.control.CheckBox
        RF2CheckBox            matlab.ui.control.CheckBox
        RF1CheckBox            matlab.ui.control.CheckBox
        RF0CheckBox            matlab.ui.control.CheckBox
        ForwardPanel           matlab.ui.container.Panel
        ActionButton           matlab.ui.control.Button
        ForwardButton          matlab.ui.control.Button
        Theta3Slider           matlab.ui.control.Slider
        Theta3SliderLabel      matlab.ui.control.Label
        theta3EditField        matlab.ui.control.NumericEditField
        Theta2Slider           matlab.ui.control.Slider
        Theta2SliderLabel      matlab.ui.control.Label
        theta2EditField        matlab.ui.control.NumericEditField
        Theta1Slider           matlab.ui.control.Slider
        Theta1SliderLabel      matlab.ui.control.Label
        theta1EditField        matlab.ui.control.NumericEditField
        EndeffectorstatePanel  matlab.ui.container.Panel
        YawEditField           matlab.ui.control.NumericEditField
        YawEditFieldLabel      matlab.ui.control.Label
        PitchEditField         matlab.ui.control.NumericEditField
        PitchEditFieldLabel    matlab.ui.control.Label
        RollEditField          matlab.ui.control.NumericEditField
        RollEditFieldLabel     matlab.ui.control.Label
        ZEditFieldLabel        matlab.ui.control.Label
        ZEditField             matlab.ui.control.NumericEditField
        YEditField             matlab.ui.control.NumericEditField
        YEditFieldLabel        matlab.ui.control.Label
        XEditField             matlab.ui.control.NumericEditField
        XEditFieldLabel        matlab.ui.control.Label
        UIAxes_2               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
    
    Theta1_input = 0 % Thuộc tính lưu giá trị của Theta1
    Theta2_input = 0 % Thuộc tính lưu giá trị của Theta2
    Theta3_input = 0 % Thuộc tính lưu giá trị của Theta3
    x_input      = 0 
    y_input      = 0
    z_input      = 0
    %% Private Variable for Robot
    %d
    d1 = 10
    d2 = 0
    d3 = 0
    %a
    a1 = 0
    a2 = 10 
    a3 = 10
    %alpha
    alpha1 = 90
    alpha2 = 0
    alpha3 = 0
    %theta
    theta1 = 0
    theta2 = 0
    theta3 = 0
    %Transformation matrices
    A10
    A20
    A30
    A40
    %end_effector 
    xe = 0
    ye = 0
    ze = 0
    %Frame 0
    X_RF0 = [0,  0, 0;
             10, 0, 0];
    Y_RF0 = [0  0  0;
             0  10 0];
    Z_RF0 = [0  0  0;
             0  0  10];
    % Roll Picth Yaw
    roll_angle   = 0
    pitch_angle  = 0
    yaw_angle    = 0

    % Delta Theta
    del_theta1 = 10
    del_theta2 = 10
    del_theta3 = 10

    % Temp theta
    theta1_temp = 0;
    theta2_temp = 0;
    theta3_temp = 0;
    end


    methods (Access = private)
        %% Transformation Matrix
        function A = Trans_Matrix_Calc(~,theta,d,a,alpha)
             theta = deg2rad(theta);
             alpha = deg2rad(alpha);
            A = [cos(theta) ,-cos(alpha)*sin(theta) ,sin(alpha)*sin(theta)  ,a*cos(theta);
                 sin(theta) ,cos(alpha)*cos(theta)  ,-sin(alpha)*cos(theta) ,a*sin(theta);
                 0          ,sin(alpha)             ,cos(alpha)             ,d           ;
                 0          ,0                      ,0                      ,1           ];
        end
        %% Rotate function
        function y = rotY(~,theta)
            theta = deg2rad(theta);
            y = [cos(theta),  0,  sin(theta),  0; 
                 0         ,  1,  0         ,  0; 
                -sin(theta),  0,  cos(theta),  0; 
                0          ,  0,  0         ,  1];
        end
        function y = rotZ(~,theta)
            theta = deg2rad(theta);
            y = [cos(theta),  -sin(theta),  0,  0; 
                 sin(theta),  cos(theta),   0,  0; 
                 0,           0,            1,  0; 
                 0,           0,            0,  1];
        end
        %% Draw base function
        function ve_de(app)
            % Khai báo thông số cho cơ sở
            r_base = 2; % Bán kính cơ sở
            h_base = 10; % Chiều cao cơ sở
            n = 100;    % Số điểm trên hình trụ
    
            % Tạo hình trụ
            [X, Y, Z] = cylinder(r_base, n);
            Z = Z * h_base; % Tính chiều cao của hình trụ
    
            % Vẽ hình trụ
            surf(app.UIAxes_2, X, Y, Z,"EdgeColor","flat","FaceColor","b"); % Thay đổi màu sắc nếu cần
            hold(app.UIAxes_2, "on");
    
            % Thiết lập nhãn và giới hạn cho các trục
            xlabel(app.UIAxes_2, "X");
            ylabel(app.UIAxes_2, "Y");
            zlabel(app.UIAxes_2, "Z");
            xlim(app.UIAxes_2, [-30, 30]);
            ylim(app.UIAxes_2, [-30, 30]);
            zlim(app.UIAxes_2, [0, 30]);
    
            % Vẽ mặt đáy
            fill3(app.UIAxes_2, X(1, :), Y(1, :), Z(2, :), 'b'); % Màu sắc mặt đáy
            
        end
        %% Draw joint1
        function draw_joint_1(app,theta1)
            % khởi tạo joint ban đầu
            r_joint1 = 2;
            h_joint1 = 6;
            n = 100;
            [X,Y,Z] = cylinder(r_joint1,n);
            Z = Z * h_joint1;
            Z = Z - h_joint1/2;
            % xoay joint1
            A = [X(1,:); Y(1,:); Z(1,:); ones(1,size(X,2))];
            B = [X(2,:); Y(2,:); Z(2,:); ones(1,size(X,2))];
            app.A10 = Trans_Matrix_Calc(app, theta1, app.d1, app.a1, app.alpha1);
            A_new = app.A10*A;
            B_new = app.A10*B;
            X(1,:) = A_new(1,:);X(2,:) = B_new(1,:);
            Y(1,:) = A_new(2,:);Y(2,:) = B_new(2,:);
            Z(1,:) = A_new(3,:);Z(2,:) = B_new(3,:);
            surf(app.UIAxes_2, X, Y, Z,"FaceColor","b"); % Thay đổi màu sắc nếu cần
            hold(app.UIAxes_2, "on");
            % Vẽ mặt đáy
            fill3(app.UIAxes_2, X(1, :), Y(1, :), Z(1, :), 'b',"EdgeColor","none"); % Màu sắc mặt đáy
            fill3(app.UIAxes_2, X(2, :), Y(2, :), Z(2, :), 'b',"EdgeColor","none"); % Màu sắc mặt đáy
        end
        %% Draw joint 2
        function draw_joint_2(app,theta2)
            % khởi tạo joint ban đầu
            r_joint2 = 2;
            h_joint2 = 6;
            n = 100;
            [X,Y,Z] = cylinder(r_joint2,n);
            Z = Z * h_joint2;
            Z = Z - h_joint2/2;
            % xoay joint2
            A = [X(1,:); Y(1,:); Z(1,:); ones(1,size(X,2))];
            B = [X(2,:); Y(2,:); Z(2,:); ones(1,size(X,2))];
            app.A20 = app.A10*Trans_Matrix_Calc(app,theta2,app.d2,app.a2,app.alpha2);
            A_new = app.A20*A;
            B_new = app.A20*B;
            X(1,:) = A_new(1,:);X(2,:) = B_new(1,:);
            Y(1,:) = A_new(2,:);Y(2,:) = B_new(2,:);
            Z(1,:) = A_new(3,:);Z(2,:) = B_new(3,:);
            surf(app.UIAxes_2, X, Y, Z,"FaceColor","b"); % Thay đổi màu sắc nếu cần
            hold(app.UIAxes_2, "on");
            fill3(app.UIAxes_2, X(1, :), Y(1, :), Z(1, :), 'b',"EdgeColor","none"); % Màu sắc mặt đáy
            fill3(app.UIAxes_2, X(2, :), Y(2, :), Z(2, :), 'b',"EdgeColor","none"); % Màu sắc mặt đáy
        end
        %% Draw link 2
        function draw_link_2(app)
            % khởi tạo link ban đầu
            X = [-3 12 14 14 12 -3 -3;
                -3 12 14 14 12 -3 -3];
            X = X - app.a2;
            Y = [-0.5 -0.5 -0.25 0.25 0.5 0.5 -0.5;
                 -0.5 -0.5 -0.25 0.25 0.5 0.5 -0.5];
            Z = [-1 -1 -1 -1 -1 -1 -1;
                 -3 -3 -3 -3 -3 -3 -3];
            Y = 4*Y;
            % xoay link1
            A = [X(1,:); Y(1,:); Z(1,:); ones(1,size(X,2))];
            B = [X(2,:); Y(2,:); Z(2,:); ones(1,size(X,2))];
            A_new = app.A20*A;
            B_new = app.A20*B;
            X(1,:) = A_new(1,:);X(2,:) = B_new(1,:);
            Y(1,:) = A_new(2,:);Y(2,:) = B_new(2,:);
            Z(1,:) = A_new(3,:);Z(2,:) = B_new(3,:);
            surf(app.UIAxes_2, X, Y, Z,"FaceColor","b","EdgeColor","none"); % Thay đổi màu sắc nếu cần
            fill3(app.UIAxes_2, X(1, :), Y(1, :), Z(1, :), 'b'); % Màu sắc mặt đáy
            fill3(app.UIAxes_2, X(2, :), Y(2, :), Z(2, :), 'b'); % Màu sắc mặt đáys
        end
        %% Draw link3
        function draw_link_3(app,theta3)
            % khởi tạo link ban đầu
            X = [-3 8 10 10 8 -3 -3;
                 -3 8 10 10 8 -3 -3];
            Y = [-0.5 -0.5 -0.25 0.25 0.5 0.5 -0.5;
                 -0.5 -0.5 -0.25 0.25 0.5 0.5 -0.5];
            Z = [1  1  1  1  1  1  1;
                -1 -1 -1 -1 -1 -1 -1];
            X = X - app.a3;
            Z = 1*Z;
            Y = 4*Y;
            % xoay link1
            A = [X(1,:); Y(1,:); Z(1,:); ones(1,size(X,2))];
            B = [X(2,:); Y(2,:); Z(2,:); ones(1,size(X,2))];
            app.A30 = app.A20*Trans_Matrix_Calc(app,theta3,app.d3,app.a3,app.alpha3);
            A_new = app.A30*A;
            B_new = app.A30*B;
            X(1,:) = A_new(1,:);X(2,:) = B_new(1,:);
            Y(1,:) = A_new(2,:);Y(2,:) = B_new(2,:);
            Z(1,:) = A_new(3,:);Z(2,:) = B_new(3,:);
            surf(app.UIAxes_2, X, Y, Z,"FaceColor","b","EdgeColor","none"); % Thay đổi màu sắc nếu cần
            fill3(app.UIAxes_2, X(1, :), Y(1, :), Z(1, :), 'b'); % Màu sắc mặt đáy
            fill3(app.UIAxes_2, X(2, :), Y(2, :), Z(2, :), 'b'); % Màu sắc mặt đáys
        end
        %% Draw End Effector
        function draw_end_effector(app)
            %Data end effector ban dau
            X = [-1 0 1 1 0 -1 -1;
                -1 0 1 1 0 -1 -1];
            Y = [ -0.5 -0.5 -0.5 0.5 0.5  0.5  -0.5;
                  -0.5 -0.5 -0.5 0.5 0.5  0.5  -0.5];
            Z = [ 1 0 1 1 0  1  1;
                  0 0 0 0 0  0  0];
            A = [X(1,:); Y(1,:); Z(1,:); ones(1,size(X,2))];
            B = [X(2,:); Y(2,:); Z(2,:); ones(1,size(X,2))];
            A_new = rotY(app,90)*A;
            B_new = rotY(app,90)*B;
            X(1,:) = A_new(1,:);X(2,:) = B_new(1,:);
            Y(1,:) = A_new(2,:);Y(2,:) = B_new(2,:);
            Z(1,:) = A_new(3,:);Z(2,:) = B_new(3,:);
            A = [X(1,:); Y(1,:); Z(1,:); ones(1,size(X,2))];
            B = [X(2,:); Y(2,:); Z(2,:); ones(1,size(X,2))];
            A_new = app.A30*A;
            B_new = app.A30*B;
            X(1,:) = A_new(1,:);X(2,:) = B_new(1,:);
            Y(1,:) = A_new(2,:);Y(2,:) = B_new(2,:);
            Z(1,:) = A_new(3,:);Z(2,:) = B_new(3,:);
            surf(app.UIAxes_2, X, Y, Z,"FaceColor","y"); % Thay đổi màu sắc nếu cần
            fill3(app.UIAxes_2, X(1, :), Y(1, :), Z(1, :), 'y',"EdgeColor","none"); % Màu sắc mặt đáy
            fill3(app.UIAxes_2, X(2, :), Y(2, :), Z(2, :), 'y'); % Màu sắc mặt đáys
        end
        
        %% Draw RF0 frame
        function draw_RF0(app)
            A = [app.X_RF0(1,:); app.Y_RF0(1,:); app.Z_RF0(1,:); ones(1,size(app.X_RF0,2))];
            B = [app.X_RF0(2,:); app.Y_RF0(2,:); app.Z_RF0(2,:); ones(1,size(app.X_RF0,2))];
            XS = A(1,:);XE = B(1,:);
            YS = A(2,:);YE = B(2,:);
            ZS = A(3,:);ZE = B(3,:);
            if(app.RF0CheckBox.Value == 1)
                quiver3(app.UIAxes_2,XS(1,1),YS(1,1),ZS(1,1),XE(1,1),YE(1,1),ZE(1,1),'r','LineWidth', 2); % X-axis (Red)
                hold(app.UIAxes_2, "on");
                quiver3(app.UIAxes_2,XS(1,2),YS(1,2),ZS(1,2),XE(1,2),YE(1,2),ZE(1,2),'g','LineWidth', 2); % Y-axis (Blue)
                quiver3(app.UIAxes_2,XS(1,3),YS(1,3),ZS(1,3),XE(1,3),YE(1,3),ZE(1,3),'b','LineWidth', 2); % Z-axis (Green)
            end
        end
        %% Draw RFn frame
        function draw_RF(app,n)
            switch n
               case 1
                   A = app.A10;
               case 2
                   A = app.A20;
               case 3
                   A = app.A30;
           end
            U = [1 0 0]*10;
            V = [0 1 0]*10;
            W = [0 0 1]*10;
            P = [0;0;0;1];         
            SP = A*P;
            EP = A*[U;V;W;zeros(size(U))];
            X = [1 1 1]*SP(1);
            Y = [1 1 1]*SP(2);
            Z = [1 1 1]*SP(3);
            U = EP(1,:);
            V = EP(2,:);
            W = EP(3,:);
            hold(app.UIAxes_2, "on");
            quiver3(app.UIAxes_2,X(1,1),Y(1,1),Z(1,1),U(1,1),V(1,1),W(1,1),'r','LineWidth', 2); % X-axis (Red)
            quiver3(app.UIAxes_2,X(1,2),Y(1,2),Z(1,2),U(1,2),V(1,2),W(1,2),'g','LineWidth', 2); % Y-axis (Blue)
            quiver3(app.UIAxes_2,X(1,3),Y(1,3),Z(1,3),U(1,3),V(1,3),W(1,3),'b','LineWidth', 2); % Z-axis (Green)
            hold(app.UIAxes_2, "off");
        end
        %% Draw robot
        function draw_robot(app,theta1,theta2,theta3)
            hold(app.UIAxes_2, "off");
            ve_de(app);
            draw_joint_1(app,theta1);
            draw_joint_2(app,theta2);
            draw_link_2(app);
            draw_link_3(app,theta3);
            draw_end_effector(app);
            end_effector_cal(app);
            angle_calc(app);
            % drawRF
            if(app.RF0CheckBox.Value == 1)
                draw_RF0(app);
            end
            if(app.RF1CheckBox.Value == 1)
                draw_RF(app,1);
            end
            if(app.RF2CheckBox.Value == 1)
                draw_RF(app,2);
            end
            if(app.RF3CheckBox.Value == 1)
                draw_RF(app,3);
            end
        end
        %% Calculation Roll-Pitch-Yaw Angle
        function angle_calc(app)
            c_pitch = sqrt(app.A30(3,2)^2 + app.A30(3,3)^2);
            s_picth = -app.A30(3,1);
            app.pitch_angle = atan2(s_picth,c_pitch);
            if (app.pitch_angle == pi/2)
                app.yaw_angle = 0;
                app.roll_angle = atan2(app.A30(1,2), app.A30(2,2));
            elseif (app.pitch_angle == -pi/2)
                app.yaw_angle = 0;
                app.roll_angle = -atan2(app.A30(1,2), app.A30(2,2));
            else
            s_roll  = app.A30(3,2)/c_pitch;
            c_roll  = app.A30(3,3)/c_pitch;
            app.roll_angle    = atan2(s_roll,c_roll);
            app.yaw_angle     = atan2(app.A30(2,1)/c_pitch,app.A30(1,1)/c_pitch);
            end
            app.RollEditField.Value  = rad2deg(app.roll_angle);
            app.PitchEditField.Value = rad2deg(app.pitch_angle);
            app.YawEditField.Value   = rad2deg(app.yaw_angle);
        end
        %% Calculation End effector position
        function end_effector_cal(app)
            P = app.A30*[0; 0; 0; 1];
            app.xe = P(1,:);
            app.ye = P(2,:);
            app.ze = P(3,:);
            app.XEditField.Value = app.xe;
            app.YEditField.Value = app.ye;
            app.ZEditField.Value = app.ze;
        end
        %% Calculation inverse kinematic
        function inverse_kinematic(app,pwx,pwy,pwz)
            pwz         = pwz - app.d1;
            sum         = pwx^2 + pwy^2 + pwz^2;
            if(abs(app.a2-app.a3) < sqrt(sum) && sqrt(sum) < app.a2+app.a3)
                sum_xy      = pwx^2 + pwy^2;
                c_theta3    = (sum-app.a2^2-app.a3^2)/(2*app.a2*app.a3);
                s_theta3    = -sqrt(1-c_theta3^2);
                theta3_inv  = atan2(s_theta3,c_theta3);
                MS          = app.a2^2 + app.a3^2 + 2*app.a2*app.a3*c_theta3;
                c_theta2    = (sqrt(sum_xy)*(app.a2+app.a2*c_theta3)+pwz*app.a3*s_theta3)/MS;
                s_theta2    = (-sqrt(sum_xy)*app.a3*s_theta3+pwz*(app.a2+app.a3*c_theta3))/MS;
                theta2_inv  = atan2(s_theta2,c_theta2);
                theta1_inv  = atan2(pwy,pwx);
                draw_robot(app,rad2deg(theta1_inv),rad2deg(theta2_inv),rad2deg(theta3_inv));
                app.theta1EditField_inv.Value = rad2deg(theta1_inv);
                app.theta2EditField_inv.Value = rad2deg(theta2_inv);
                app.theta3EditField_inv.Value = rad2deg(theta3_inv);
            else
                msgbox("Wrong Parameter","Warning");
            end
        end
        %% Movement - TEST
        function robot_move(app,theta1,theta2,theta3)
            err_theta1 = theta1 - app.theta1_temp;
            err_theta2 = theta2 - app.theta2_temp;
            err_theta3 = theta3 - app.theta3_temp;
            % Determine direction
            if err_theta1 < 0 
                dir1 = -1;
            else
                dir1 = 1;
            end
            
            if err_theta2 < 0 
                dir2 = -1;
            else
                dir2 = 1;
            end
            
            if err_theta3 < 0 
                dir3 = -1;
            else
                dir3 = 1;
            end
            % Movement loops
            while (err_theta1 ~= 0)||(err_theta2 ~= 0)||(err_theta3 ~= 0)
                if abs(err_theta1) > app.del_theta1
                    app.theta1_temp = app.theta1_temp + app.del_theta1*dir1;
                else
                    app.theta1_temp = theta1;
                end
                
                if abs(err_theta2) > app.del_theta2
                    app.theta2_temp = app.theta2_temp + app.del_theta2*dir2;
                else
                    app.theta2_temp = theta2;
                end
                 if abs(err_theta3) > app.del_theta3
                    app.theta3_temp = app.theta3_temp + app.del_theta3*dir3;
                else
                    app.theta3_temp = theta3;
                 end
                 draw_robot(app,app.theta1_temp,app.theta2_temp,app.theta3_temp);
                 err_theta1 = theta1 - app.theta1_temp;
                 err_theta2 = theta2 - app.theta2_temp;
                 err_theta3 = theta3 - app.theta3_temp;
                 pause(0.00001);
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, d1)
            
        end

        % Value changed function: Theta1Slider
        function Theta1SliderValueChanged(app, event)
            app.Theta1_input = app.Theta1Slider.Value;
            app.theta1EditField.Value = app.Theta1_input;

        end

        % Value changed function: theta1EditField
        function theta1EditFieldValueChanged(app, event)
            app.Theta1_input = app.theta1EditField.Value;
            app.Theta1Slider.Value = app.Theta1_input;
            
        end

        % Value changed function: Theta2Slider
        function Theta2SliderValueChanged(app, event)
            app.Theta2_input = app.Theta2Slider.Value;
            app.theta2EditField.Value = app.Theta2_input;

        end

        % Value changed function: theta2EditField
        function theta2EditFieldValueChanged(app, event)
            app.Theta2_input = app.theta2EditField.Value;
            app.Theta2Slider.Value = app.Theta2_input;

        end

        % Value changed function: Theta3Slider
        function Theta3SliderValueChanged(app, event)
            app.Theta3_input = app.Theta3Slider.Value;
            app.theta3EditField.Value = app.Theta3_input;

        end

        % Value changed function: theta3EditField
        function theta3EditFieldValueChanged(app, event)
            app.Theta3_input = app.theta3EditField.Value;
            app.Theta3Slider.Value = app.Theta3_input;

        end

        % Button pushed function: ForwardButton
        function ForwardButtonPushed(app, event)

            app.theta1_temp = app.Theta1_input;
            app.theta2_temp = app.Theta2_input;
            app.theta3_temp = app.Theta3_input;
            draw_robot(app,app.theta1_temp,app.theta2_temp,app.theta3_temp);
        end

        % Value changed function: RF0CheckBox
        function RF0CheckBoxValueChanged(app, event)

        end

        % Value changed function: RF1CheckBox
        function RF1CheckBoxValueChanged(app, event)

        end

        % Value changed function: RF2CheckBox
        function RF2CheckBoxValueChanged(app, event)

        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            hold(app.UIAxes_2, "off");
            %% Reset theta
            app.Theta1_input = 0;
            app.Theta2_input = 0;
            app.Theta3_input = 0;
            app.theta1_temp = app.Theta1_input;
            app.theta2_temp = app.Theta2_input;
            app.theta3_temp = app.Theta3_input;
            app.Theta1Slider.Value = app.Theta1_input;
            app.Theta2Slider.Value = app.Theta2_input;
            app.Theta3Slider.Value = app.Theta3_input;
            app.theta1EditField.Value = app.Theta1_input;
            app.theta2EditField.Value = app.Theta2_input;
            app.theta3EditField.Value = app.Theta3_input;
            draw_robot(app,0,0,0);
        end

        % Button pushed function: ActionButton
        function ActionButtonPushed(app, event)
            robot_move(app,app.Theta1_input,app.Theta2_input,app.Theta3_input);
        end

        % Button pushed function: InverseButton
        function InverseButtonPushed(app, event)
            app.x_input = app.XEditField_inv.Value;
            app.y_input = app.YEditField_inv.Value;
            app.z_input = app.ZEditField_inv.Value;
            inverse_kinematic(app,app.x_input,app.y_input,app.z_input);
            end_effector_cal(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Robot Coordinate')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.XLim = [0 20];
            app.UIAxes_2.YLim = [0 20];
            app.UIAxes_2.ZLim = [0 20];
            app.UIAxes_2.XAxisLocation = 'origin';
            app.UIAxes_2.YAxisLocation = 'origin';
            app.EndeffectorstatePanel = uipanel(app.UIFigure);
            app.EndeffectorstatePanel.Title = 'End effector state';
            app.XEditFieldLabel = uilabel(app.EndeffectorstatePanel);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.EndeffectorstatePanel, 'numeric');
            app.YEditFieldLabel = uilabel(app.EndeffectorstatePanel);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.EndeffectorstatePanel, 'numeric');
            app.ZEditField = uieditfield(app.EndeffectorstatePanel, 'numeric');
            app.ZEditFieldLabel = uilabel(app.EndeffectorstatePanel);
            app.ZEditFieldLabel.Text = 'Z';
            app.RollEditFieldLabel = uilabel(app.EndeffectorstatePanel);
            app.RollEditFieldLabel.Text = 'Roll';
            app.RollEditField = uieditfield(app.EndeffectorstatePanel, 'numeric');
            app.PitchEditFieldLabel = uilabel(app.EndeffectorstatePanel);
            app.PitchEditFieldLabel.Text = 'Pitch';
            app.PitchEditField = uieditfield(app.EndeffectorstatePanel, 'numeric');
            app.YawEditFieldLabel = uilabel(app.EndeffectorstatePanel);
            app.YawEditFieldLabel.Text = 'Yaw';
            app.YawEditField = uieditfield(app.EndeffectorstatePanel, 'numeric');
            app.ForwardPanel = uipanel(app.UIFigure);
            app.ForwardPanel.Title = 'Forward';
            app.theta1EditField = uieditfield(app.ForwardPanel, 'numeric');
            app.theta1EditField.ValueChangedFcn = createCallbackFcn(app, @theta1EditFieldValueChanged, true);
            app.Theta1SliderLabel = uilabel(app.ForwardPanel);
            app.Theta1SliderLabel.Text = 'Theta 1';
            app.Theta1Slider = uislider(app.ForwardPanel);
            app.Theta1Slider.Limits = [-135 135];
            app.Theta1Slider.ValueChangedFcn = createCallbackFcn(app, @Theta1SliderValueChanged, true);
            app.theta2EditField = uieditfield(app.ForwardPanel, 'numeric');
            app.theta2EditField.ValueChangedFcn = createCallbackFcn(app, @theta2EditFieldValueChanged, true);
            app.Theta2SliderLabel = uilabel(app.ForwardPanel);
            app.Theta2SliderLabel.Text = 'Theta 2';
            app.Theta2Slider = uislider(app.ForwardPanel);
            app.Theta2Slider.Limits = [-90 90];
            app.Theta2Slider.ValueChangedFcn = createCallbackFcn(app, @Theta2SliderValueChanged, true);
            app.theta3EditField = uieditfield(app.ForwardPanel, 'numeric');
            app.theta3EditField.ValueChangedFcn = createCallbackFcn(app, @theta3EditFieldValueChanged, true);
            app.Theta3SliderLabel = uilabel(app.ForwardPanel);
            app.Theta3SliderLabel.Text = 'Theta 3';
            app.Theta3Slider = uislider(app.ForwardPanel);
            app.Theta3Slider.Limits = [-90 90];
            app.Theta3Slider.ValueChangedFcn = createCallbackFcn(app, @Theta3SliderValueChanged, true);
            app.ForwardButton = uibutton(app.ForwardPanel, 'push');
            app.ForwardButton.ButtonPushedFcn = createCallbackFcn(app, @ForwardButtonPushed, true);
            app.ForwardButton.Text = 'Forward';
            app.ActionButton = uibutton(app.ForwardPanel, 'push');
            app.ActionButton.ButtonPushedFcn = createCallbackFcn(app, @ActionButtonPushed, true);
            app.ActionButton.Text = 'Action';
            app.RFDisplayPanel = uipanel(app.UIFigure);
            app.RFDisplayPanel.Title = 'RF Display';
            app.RF0CheckBox = uicheckbox(app.RFDisplayPanel);
            app.RF0CheckBox.ValueChangedFcn = createCallbackFcn(app, @RF0CheckBoxValueChanged, true);
            app.RF0CheckBox.Text = 'RF0';
            app.RF1CheckBox = uicheckbox(app.RFDisplayPanel);
            app.RF1CheckBox.ValueChangedFcn = createCallbackFcn(app, @RF1CheckBoxValueChanged, true);
            app.RF1CheckBox.Text = 'RF1';
            app.RF2CheckBox = uicheckbox(app.RFDisplayPanel);
            app.RF2CheckBox.ValueChangedFcn = createCallbackFcn(app, @RF2CheckBoxValueChanged, true);
            app.RF2CheckBox.Text = 'RF2';
            app.RF3CheckBox = uicheckbox(app.RFDisplayPanel);
            app.RF3CheckBox.Text = 'RF3';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.InverseKinematicPanel = uipanel(app.UIFigure);
            app.InverseKinematicPanel.Title = 'Inverse Kinematic';
            app.XEditField_2Label = uilabel(app.InverseKinematicPanel);
            app.XEditField_2Label.Text = 'X';
            app.XEditField_inv = uieditfield(app.InverseKinematicPanel, 'numeric');
            app.YEditField_2Label = uilabel(app.InverseKinematicPanel);
            app.YEditField_2Label.Text = 'Y';
            app.YEditField_inv = uieditfield(app.InverseKinematicPanel, 'numeric');
            app.ZEditField_inv = uieditfield(app.InverseKinematicPanel, 'numeric');
            app.ZEditField_2Label = uilabel(app.InverseKinematicPanel);
            app.ZEditField_2Label.Text = 'Z';
            app.InverseButton = uibutton(app.InverseKinematicPanel, 'push');
            app.InverseButton.ButtonPushedFcn = createCallbackFcn(app, @InverseButtonPushed, true);
            app.InverseButton.Text = 'Inverse';
            app.XEditField_2Label_2 = uilabel(app.InverseKinematicPanel);
            app.XEditField_2Label_2.Text = 'Theta1';
            app.theta1EditField_inv = uieditfield(app.InverseKinematicPanel, 'numeric');
            app.XEditField_2Label_3 = uilabel(app.InverseKinematicPanel);
            app.XEditField_2Label_3.Text = 'Theta2';
            app.theta2EditField_inv = uieditfield(app.InverseKinematicPanel, 'numeric');
            app.XEditField_2Label_4 = uilabel(app.InverseKinematicPanel);
            app.XEditField_2Label_4.Text = 'Theta3';
            app.theta3EditField_inv = uieditfield(app.InverseKinematicPanel, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BTL_Robot_2112447_PUMA(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end