classdef BTL_Robot_2112447_Yaskawa < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        KinematicTab              matlab.ui.container.Tab
        PVAParameterPanel         matlab.ui.container.Panel
        v3_maxEditField           matlab.ui.control.NumericEditField
        v3_maxEditFieldLabel      matlab.ui.control.Label
        a3_maxEditField           matlab.ui.control.NumericEditField
        a3_maxEditFieldLabel      matlab.ui.control.Label
        a2_maxEditField           matlab.ui.control.NumericEditField
        a2_maxEditFieldLabel      matlab.ui.control.Label
        v2_maxEditField           matlab.ui.control.NumericEditField
        v2_maxEditFieldLabel      matlab.ui.control.Label
        InitButton                matlab.ui.control.Button
        a1_maxEditField           matlab.ui.control.NumericEditField
        a1_maxEditFieldLabel      matlab.ui.control.Label
        v1_maxEditField           matlab.ui.control.NumericEditField
        v_maxLabel                matlab.ui.control.Label
        JointstatePanel           matlab.ui.container.Panel
        ZEditField_2Label_2       matlab.ui.control.Label
        theta3EditField           matlab.ui.control.NumericEditField
        theta2EditField           matlab.ui.control.NumericEditField
        YEditField_2Label_2       matlab.ui.control.Label
        theta1EditField           matlab.ui.control.NumericEditField
        theta_1Label              matlab.ui.control.Label
        StartButton               matlab.ui.control.Button
        InverseKinematicPanel     matlab.ui.container.Panel
        MoveButton_2              matlab.ui.control.Button
        theta3EditField_inv       matlab.ui.control.NumericEditField
        XEditField_2Label_4       matlab.ui.control.Label
        theta2EditField_inv       matlab.ui.control.NumericEditField
        XEditField_2Label_3       matlab.ui.control.Label
        theta1EditField_inv       matlab.ui.control.NumericEditField
        XEditField_2Label_2       matlab.ui.control.Label
        InverseButton             matlab.ui.control.Button
        ZEditField_2Label         matlab.ui.control.Label
        ZEditField_inv            matlab.ui.control.NumericEditField
        YEditField_inv            matlab.ui.control.NumericEditField
        YEditField_2Label         matlab.ui.control.Label
        XEditField_inv            matlab.ui.control.NumericEditField
        XEditField_2Label         matlab.ui.control.Label
        ResetButton               matlab.ui.control.Button
        RFDisplayPanel            matlab.ui.container.Panel
        WorkplaceCheckbox         matlab.ui.control.CheckBox
        RF3CheckBox               matlab.ui.control.CheckBox
        RF2CheckBox               matlab.ui.control.CheckBox
        RF1CheckBox               matlab.ui.control.CheckBox
        RF0CheckBox               matlab.ui.control.CheckBox
        ForwardKinematicPanel     matlab.ui.container.Panel
        spinner_theta3            matlab.ui.control.Spinner
        spinner_theta2            matlab.ui.control.Spinner
        spinner_theta1            matlab.ui.control.Spinner
        MoveButton                matlab.ui.control.Button
        ForwardButton             matlab.ui.control.Button
        theta_3inputSlider        matlab.ui.control.Slider
        theta_3inputSliderLabel   matlab.ui.control.Label
        theta_2inputSlider        matlab.ui.control.Slider
        theta_2inputSliderLabel   matlab.ui.control.Label
        theta_1inputSlider        matlab.ui.control.Slider
        theta_1inputSliderLabel   matlab.ui.control.Label
        EndeffectorstatePanel     matlab.ui.container.Panel
        YawphiEditField           matlab.ui.control.NumericEditField
        YawphiEditFieldLabel      matlab.ui.control.Label
        PitchthetaEditField       matlab.ui.control.NumericEditField
        PitchthetaEditFieldLabel  matlab.ui.control.Label
        RollpsiEditField          matlab.ui.control.NumericEditField
        RollpsiEditFieldLabel     matlab.ui.control.Label
        ZEditFieldLabel           matlab.ui.control.Label
        ZEditField                matlab.ui.control.NumericEditField
        YEditField                matlab.ui.control.NumericEditField
        YEditFieldLabel           matlab.ui.control.Label
        XEditField                matlab.ui.control.NumericEditField
        XEditFieldLabel           matlab.ui.control.Label
        TrajectoryPlaningTab      matlab.ui.container.Tab
        UIAxes_theta3_2d          matlab.ui.control.UIAxes
        UIAxes_theta3_d           matlab.ui.control.UIAxes
        UIAxes_theta3             matlab.ui.control.UIAxes
        UIAxes_theta2_2d          matlab.ui.control.UIAxes
        UIAxes_theta2_d           matlab.ui.control.UIAxes
        UIAxes_theta2             matlab.ui.control.UIAxes
        UIAxes_theta1_2d          matlab.ui.control.UIAxes
        UIAxes_theta1_d           matlab.ui.control.UIAxes
        UIAxes_theta1             matlab.ui.control.UIAxes
        UIAxes_2                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
    %% Emergency STOP
    stop_flag    = 0
    Theta1_input = 0 % Thuộc tính lưu giá trị của Theta1
    Theta2_input = 0 % Thuộc tính lưu giá trị của Theta2
    Theta3_input = 0 % Thuộc tính lưu giá trị của Theta3
    theta1_inv   = 0
    theta2_inv   = 0
    theta3_inv   = 0
    x_input      = 0 
    y_input      = 0
    z_input      = 0
    %% Private Variable for Robot
    % DH - Parameter -GP7
    %d
    d1 = 330;
    d2 = 0;
    d3 = 0;
    %a
    a1 = 0;
    a2 = 385;
    a3 = 340 + 80;% 80 là độ cao của khớp nối
    %alpha
    alpha1 = 90
    alpha2 = 0
    alpha3 = 0
    %theta
    theta1     = 0
    theta2     = 0
    theta3     = 0
    theta1_max = 170
    theta1_min = -170
    theta2_max = 65
    theta2_min = -145
    theta3_max = 70
    theta3_min = -190
    %Transformation matrices
    A10
    A20
    A30
    A40
    %end_effector 
    xe = 0
    ye = 0
    ze = 0
    %Frame 0
    X_RF0 = [0,  0, 0;
             10, 0, 0];
    Y_RF0 = [0  0  0;
             0  10 0];
    Z_RF0 = [0  0  0;
             0  0  10];
    % Roll Picth Yaw
    roll_angle   = 0
    pitch_angle  = 0
    yaw_angle    = 0

    % Delta Theta
    del_theta1 = 1
    del_theta2 = 1
    del_theta3 = 1

    % Temp theta
    theta1_temp = 0;
    theta2_temp = 0;
    theta3_temp = 0;

    % Last theta
    theta1_last = 0;
    theta2_last = 0;
    theta3_last = 0;

    %PVA Parameter
    v1_max = 10;
    v2_max = 10;
    v3_max = 10;
    a1_max = 10;
    a2_max = 10;
    a3_max = 10;

    %Data Workspace
    workspace_robot = zeros(3,1);
    end


    methods (Access = private)
        %% Transformation Matrix
        function A = Trans_Matrix_Calc(~,theta,d,a,alpha)
             theta = deg2rad(theta);
             alpha = deg2rad(alpha);
            A = [cos(theta) ,-cos(alpha)*sin(theta) ,sin(alpha)*sin(theta)  ,a*cos(theta);
                 sin(theta) ,cos(alpha)*cos(theta)  ,-sin(alpha)*cos(theta) ,a*sin(theta);
                 0          ,sin(alpha)             ,cos(alpha)             ,d           ;
                 0          ,0                      ,0                      ,1           ];
        end
        %% Rotate function
        function y = rotY(~,theta)
            theta = deg2rad(theta);
            y = [cos(theta),  0,  sin(theta),  0; 
                 0         ,  1,  0         ,  0; 
                -sin(theta),  0,  cos(theta),  0; 
                0          ,  0,  0         ,  1];
        end
        function y = rotZ(~,theta)
            theta = deg2rad(theta);
            y = [cos(theta),  -sin(theta),  0,  0; 
                 sin(theta),  cos(theta),   0,  0; 
                 0,           0,            1,  0; 
                 0,           0,            0,  1];
        end
        %% Draw base function
        function ve_de(app)
            % Phần đế
            X = [-79, 79 , 79 , -79, -79;
                 -97, 97 , 97 , -97, -97];
            Y = [79 , 79 , -79, -79, 79;
                 97 , 97 , -97, -97, 97];
            Z = [200, 200, 200, 200, 200;
                 0  , 0  , 0  , 0  , 0];
            surf(app.UIAxes_2, X, Y, Z,"EdgeColor","flat","FaceColor","b"); % Thay đổi màu sắc nếu cần
            hold(app.UIAxes_2, "on");
            % Phần joint1
            r_base = 50;  % Bán kính của joint1
            h_base = 330; % Chiều cao của joint1
            n = 100;        
            
            % Tạo hình trụ
            [X, Y, Z] = cylinder(r_base, n);
            Z = Z * h_base; % Tính chiều cao của hình trụ
    
            % Vẽ hình trụ
            surf(app.UIAxes_2, X, Y, Z,"EdgeColor","flat","FaceColor","b"); % Thay đổi màu sắc nếu cần
            hold(app.UIAxes_2, "on");
    
            % Đặt nhãn và giới hạn cho các trục - IMPORTANT
            xlabel(app.UIAxes_2, "X");
            ylabel(app.UIAxes_2, "Y");
            zlabel(app.UIAxes_2, "Z");
            xlim(app.UIAxes_2, [-900, 900]);
            ylim(app.UIAxes_2, [-900, 900]);
            zlim(app.UIAxes_2, [-300, 1200]);
    
            % Vẽ mặt đáy
            fill3(app.UIAxes_2, X(1, :), Y(1, :), Z(2, :), 'b'); % Màu sắc mặt đáy
            
        end
        %% Draw joint function
        function draw_joint(app,r_joint,h_joint,A_trans)
            n = 100;
            [X,Y,Z] = cylinder(r_joint,n);
            Z = Z * h_joint;
            Z = Z - h_joint/2;
            %xoay joint
            A = [X(1,:); Y(1,:); Z(1,:); ones(1,size(X,2))];
            B = [X(2,:); Y(2,:); Z(2,:); ones(1,size(X,2))];
            A_new = A_trans*A;
            B_new = A_trans*B;
            X(1,:) = A_new(1,:);X(2,:) = B_new(1,:);
            Y(1,:) = A_new(2,:);Y(2,:) = B_new(2,:);
            Z(1,:) = A_new(3,:);Z(2,:) = B_new(3,:);
            surf(app.UIAxes_2, X, Y, Z,"FaceColor","b"); % Thay đổi màu sắc nếu cần
            fill3(app.UIAxes_2,X(1,:),Y(1,:),Z(1,:),'y');
            fill3(app.UIAxes_2,X(2,:),Y(2,:),Z(2,:),'y');
        end

        function draw_joint_1(app,theta1)
            % khởi tạo joint ban đầu
            r_joint1 = 2;
            h_joint1 = 6;
            n = 100;
            [X,Y,Z] = cylinder(r_joint1,n);
            Z = Z * h_joint1;
            Z = Z - h_joint1/2;
            % xoay joint1
            A = [X(1,:); Y(1,:); Z(1,:); ones(1,size(X,2))];
            B = [X(2,:); Y(2,:); Z(2,:); ones(1,size(X,2))];
            app.A10 = Trans_Matrix_Calc(app, theta1, app.d1, app.a1, app.alpha1);
            A_new = app.A10*A;
            B_new = app.A10*B;
            X(1,:) = A_new(1,:);X(2,:) = B_new(1,:);
            Y(1,:) = A_new(2,:);Y(2,:) = B_new(2,:);
            Z(1,:) = A_new(3,:);Z(2,:) = B_new(3,:);
            surf(app.UIAxes_2, X, Y, Z,"FaceColor","b"); % Thay đổi màu sắc nếu cần
            hold(app.UIAxes_2, "on");
            % Vẽ mặt đáy
            fill3(app.UIAxes_2, X(1, :), Y(1, :), Z(1, :), 'b',"EdgeColor","none"); % Màu sắc mặt đáy
            fill3(app.UIAxes_2, X(2, :), Y(2, :), Z(2, :), 'b',"EdgeColor","none"); % Màu sắc mặt đáy
        end
        %% Draw joint 2
        function draw_joint_2(app,theta2)
            % khởi tạo joint ban đầu
            r_joint2 = 2;
            h_joint2 = 6;
            n = 100;
            [X,Y,Z] = cylinder(r_joint2,n);
            Z = Z * h_joint2;
            Z = Z - h_joint2/2;
            % xoay joint2
            A = [X(1,:); Y(1,:); Z(1,:); ones(1,size(X,2))];
            B = [X(2,:); Y(2,:); Z(2,:); ones(1,size(X,2))];
            app.A20 = app.A10*Trans_Matrix_Calc(app,theta2,app.d2,app.a2,app.alpha2);
            A_new = app.A20*A;
            B_new = app.A20*B;
            X(1,:) = A_new(1,:);X(2,:) = B_new(1,:);
            Y(1,:) = A_new(2,:);Y(2,:) = B_new(2,:);
            Z(1,:) = A_new(3,:);Z(2,:) = B_new(3,:);
            surf(app.UIAxes_2, X, Y, Z,"FaceColor","b"); % Thay đổi màu sắc nếu cần
            hold(app.UIAxes_2, "on");
            fill3(app.UIAxes_2, X(1, :), Y(1, :), Z(1, :), 'b',"EdgeColor","none"); % Màu sắc mặt đáy
            fill3(app.UIAxes_2, X(2, :), Y(2, :), Z(2, :), 'b',"EdgeColor","none"); % Màu sắc mặt đáy
        end
        %% Draw link 2
        function draw_link_1(app)
            % khởi tạo link ban đầu
            X       = ones(2,762);
            Y       = ones(2,762);
            % first face
            x       = 0:1:380;
            y_upper = (-1/2000).*(x-190).^2+68.05;
            y_lower = (-1/2000).*(x-190).^2+68.05-100;
            %Update parameter
            i = 1;
            while i < 381
                X(1,i) = x(i);
                X(2,i) = x(i);
                i = i + 1;
            end
            i = 1;
            while i < 381
                Y(1,i) = y_upper(i);
                Y(2,i) = y_lower(i);
                i = i + 1;
            end
            %second face
            x       = 380:-1:0;
            y_upper = (-1/2000).*(x-190).^2+68.05;
            y_lower = (-1/2000).*(x-190).^2+68.05-100;
            %Update parameter
            i = 381;
            while i < 762
                X(1,i) = x(i-380);
                X(2,i) = x(i-380);
                i = i + 1;
            end
            i = 381;
            while i < 762
                Y(1,i) = y_upper(i-380);
                Y(2,i) = y_lower(i-380);
                i = i + 1;
            end
            %Final
            Z       = ones(2,762);
            Z       = Z*115;
            %Update parameter
            i = 381;
            while i < 762
                Z(1,i) = -115;
                Z(2,i) = -115;
                i = i + 1;
            end
            % Quay dau
            X(1,762) = X(1,1);
            X(2,762) = X(2,1);
            Y(1,762) = Y(1,1);
            Y(2,762) = Y(2,1);
            Z(1,762) = Z(1,1);
            Z(2,762) = Z(2,1);
            X        = X - app.a2;
            % xoay link1
            A = [X(1,:); Y(1,:); Z(1,:); ones(1,size(X,2))];
            B = [X(2,:); Y(2,:); Z(2,:); ones(1,size(X,2))];
            A_new = app.A20*A;
            B_new = app.A20*B;
            X(1,:) = A_new(1,:);X(2,:) = B_new(1,:);
            Y(1,:) = A_new(2,:);Y(2,:) = B_new(2,:);
            Z(1,:) = A_new(3,:);Z(2,:) = B_new(3,:);
            surf(app.UIAxes_2, X, Y, Z,"FaceColor","b","EdgeColor","none"); % Thay đổi màu sắc nếu cần
            fill3(app.UIAxes_2, X(1, :), Y(1, :), Z(1, :), 'b'); % Màu sắc mặt đáy
            fill3(app.UIAxes_2, X(2, :), Y(2, :), Z(2, :), 'b'); % Màu sắc mặt đáys
        end
        %% Draw link3
        function draw_link_2(app,theta3)
            % khởi tạo link ban đầu
            Y = [0 83 83  0   0;
                 0 83 83  0   0];
            X = [0 0  340 340 0;
                 0 0  340 340 0];   
            Z = [62 62 62 62 62;
                -62 -62  -62  -62  -62];
            Y = Y - 83/2;
            X = X - app.a3;
            % xoay link
            A = [X(1,:); Y(1,:); Z(1,:); ones(1,size(X,2))];
            B = [X(2,:); Y(2,:); Z(2,:); ones(1,size(X,2))];
            app.A30 = app.A20*Trans_Matrix_Calc(app,theta3,app.d3,app.a3,app.alpha3);
            A_new = app.A30*A;
            B_new = app.A30*B;
            X(1,:) = A_new(1,:);X(2,:) = B_new(1,:);
            Y(1,:) = A_new(2,:);Y(2,:) = B_new(2,:);
            Z(1,:) = A_new(3,:);Z(2,:) = B_new(3,:);
            surf(app.UIAxes_2, X, Y, Z,"FaceColor","b","EdgeColor","none"); % Thay đổi màu sắc nếu cần
            fill3(app.UIAxes_2, X(1, :), Y(1, :), Z(1, :), 'b'); % Màu sắc mặt đáy
            fill3(app.UIAxes_2, X(2, :), Y(2, :), Z(2, :), 'b'); % Màu sắc mặt đáys
        end
        %% Draw End Effector
        function draw_end_effector(app,A_trans,cd,cr,cc)
            %Data end effector ban dau
            [X, Y, Z] = cylinder(50,100);
            Z = 80*Z-80 ;
            draw_everything(app,A_trans,X,Y,Z);
            
            %End effector trong tam
            [X, Y, Z] = cylinder(10,100);
            X = X;
            Y = Y;
            Z = 80*Z-80;
            draw_everything(app,A_trans,X,Y,Z);

            %End effector trong tam
            [X, Y, Z] = cylinder(10,100);
            X = X + 40*cos(pi/3);
            Y = Y + 40*sin(pi/3);
            Z = 80*Z-80;
            draw_everything(app,A_trans,X,Y,Z);

            %End effector trong tam
            [X, Y, Z] = cylinder(10,100);
            X = X + 40*cos(2*pi/3);
            Y = Y + 40*sin(2*pi/3);
            Z = 80*Z-80;
            draw_everything(app,A_trans,X,Y,Z);

            %End effector trong tam
            [X, Y, Z] = cylinder(10,100);            
            X = X + 40*cos(pi);
            Y = Y + 40*sin(pi);
            Z = 80*Z-80;
            draw_everything(app,A_trans,X,Y,Z);

            %End effector trong tam
            [X, Y, Z] = cylinder(10,100);
            X = X + 40*cos(-pi/3);
            Y = Y + 40*sin(-pi/3);
            Z = 80*Z-80;
            draw_everything(app,A_trans,X,Y,Z);

            [X, Y, Z] = cylinder(10,100);
            X = X + 40*cos(-2*pi/3);
            Y = Y + 40*sin(-2*pi/3);
            Z = 80*Z-80;
            draw_everything(app,A_trans,X,Y,Z);

            % [X, Y, Z] = cylinder(10,100);
            % X = X + 40*cos(-pi);
            % Y = Y + 40*sin(-pi);
            % Z = 80*Z-80;
            % draw_everything(app,A_trans,X,Y,Z);
            
        end
        %% Draw end effector hole
        function draw_everything(app,A_trans,X,Y,Z)
            % Xoay end effector
            A = [X(1,:); Y(1,:); Z(1,:); ones(1,size(X,2))];
            B = [X(2,:); Y(2,:); Z(2,:); ones(1,size(X,2))];
            % Xoay end effector
            A_new = rotY(app,90)*A;
            B_new = rotY(app,90)*B;
            X(1,:) = A_new(1,:);X(2,:) = B_new(1,:);
            Y(1,:) = A_new(2,:);Y(2,:) = B_new(2,:);
            Z(1,:) = A_new(3,:);Z(2,:) = B_new(3,:);
            A = [X(1,:); Y(1,:); Z(1,:); ones(1,size(X,2))];
            B = [X(2,:); Y(2,:); Z(2,:); ones(1,size(X,2))];
            A_new = A_trans*A;
            B_new = A_trans*B;
            X(1,:) = A_new(1,:);X(2,:) = B_new(1,:);
            Y(1,:) = A_new(2,:);Y(2,:) = B_new(2,:);
            Z(1,:) = A_new(3,:);Z(2,:) = B_new(3,:);
            surf(app.UIAxes_2, X, Y, Z,"FaceColor","b"); % Thay đổi màu sắc nếu cần
            fill3(app.UIAxes_2, X(1, :), Y(1, :), Z(1, :), 'b',"EdgeColor","none"); % Màu sắc mặt đáy
            fill3(app.UIAxes_2, X(2, :), Y(2, :), Z(2, :), 'b'); % Màu sắc mặt đáy
        end
        %% Draw RF0 frame
        function draw_RF0(app)
            A = [app.X_RF0(1,:); app.Y_RF0(1,:); app.Z_RF0(1,:); ones(1,size(app.X_RF0,2))];
            B = [app.X_RF0(2,:); app.Y_RF0(2,:); app.Z_RF0(2,:); ones(1,size(app.X_RF0,2))];
            A = 20*A;
            B = 20*B;
            XS = A(1,:);XE = B(1,:);
            YS = A(2,:);YE = B(2,:);
            ZS = A(3,:);ZE = B(3,:);
            quiver3(app.UIAxes_2,XS(1,1),YS(1,1),ZS(1,1),XE(1,1),YE(1,1),ZE(1,1),'r','LineWidth', 2); % X-axis (Red)
            hold(app.UIAxes_2, "on");
            quiver3(app.UIAxes_2,XS(1,2),YS(1,2),ZS(1,2),XE(1,2),YE(1,2),ZE(1,2),'g','LineWidth', 2); % Y-axis (Blue)
            quiver3(app.UIAxes_2,XS(1,3),YS(1,3),ZS(1,3),XE(1,3),YE(1,3),ZE(1,3),'b','LineWidth', 2); % Z-axis (Green)
        end
        %% Draw RFn frame
        function draw_RF(app,n)
            switch n
               case 1
                   A = app.A10;
               case 2
                   A = app.A20;
               case 3
                   A = app.A30;
           end
            U = [1 0 0]*200;
            V = [0 1 0]*200;
            W = [0 0 1]*200;
            P = [0;0;0;1];         
            SP = A*P;
            EP = A*[U;V;W;zeros(size(U))];
            X = [1 1 1]*SP(1);
            Y = [1 1 1]*SP(2);
            Z = [1 1 1]*SP(3);
            U = EP(1,:);
            V = EP(2,:);
            W = EP(3,:);
            hold(app.UIAxes_2, "on");
            quiver3(app.UIAxes_2,X(1,1),Y(1,1),Z(1,1),U(1,1),V(1,1),W(1,1),'r','LineWidth', 2); % X-axis (Red)
            quiver3(app.UIAxes_2,X(1,2),Y(1,2),Z(1,2),U(1,2),V(1,2),W(1,2),'g','LineWidth', 2); % Y-axis (Blue)
            quiver3(app.UIAxes_2,X(1,3),Y(1,3),Z(1,3),U(1,3),V(1,3),W(1,3),'b','LineWidth', 2); % Z-axis (Green)
            hold(app.UIAxes_2, "off");
        end
        %% Draw robot
        function draw_robot(app,theta1,theta2,theta3)
            hold(app.UIAxes_2, "off");
            app.A10 = Trans_Matrix_Calc(app,theta1,app.d1,app.a1,app.alpha1);
            app.A20 = app.A10*Trans_Matrix_Calc(app,theta2,app.d2,app.a2,app.alpha2);
            app.A30 = app.A20*Trans_Matrix_Calc(app,theta3,app.d3,app.a3,app.alpha3);
            ve_de(app);
            draw_joint(app,50,230,app.A10);
            draw_joint(app,50,230,app.A20);
            draw_link_1(app);
            draw_link_2(app,theta3);
            draw_end_effector(app,app.A30,83,62,50);
            end_effector_cal(app);
            angle_calc(app);
            % drawRF
            if(app.WorkplaceCheckbox.Value == 1)
                % Chuyển đổi mảng workspace thành vector
                X_ws = reshape(app.workspace_robot(1,:), [], 1);
                Y_ws = reshape(app.workspace_robot(2,:), [], 1);
                Z_ws = reshape(app.workspace_robot(3,:), [], 1);
                % Hiển thị không gian làm việc
                scatter3(app.UIAxes_2,X_ws, Y_ws, Z_ws, '.');
                hold(app.UIAxes_2, "on");
            end
            if(app.RF0CheckBox.Value == 1)
                draw_RF0(app);
            end
            if(app.RF1CheckBox.Value == 1)
                draw_RF(app,1);
            end
            if(app.RF2CheckBox.Value == 1)
                draw_RF(app,2);
            end
            if(app.RF3CheckBox.Value == 1)
                draw_RF(app,3);
            end
            
            % Display joint
            app.theta1EditField.Value = theta1;
            app.theta2EditField.Value = theta2;
            app.theta3EditField.Value = theta3;
        end
        %% Calculation Roll-Pitch-Yaw Angle
        function angle_calc(app)
            c_pitch = sqrt(app.A30(3,2)^2 + app.A30(3,3)^2);
            s_picth = -app.A30(3,1);
            app.pitch_angle = atan2(s_picth,c_pitch);
            if (app.pitch_angle == pi/2)
                app.yaw_angle = 0;
                app.roll_angle = atan2(app.A30(1,2), app.A30(2,2));
            elseif (app.pitch_angle == -pi/2)
                app.yaw_angle = 0;
                app.roll_angle = -atan2(app.A30(1,2), app.A30(2,2));
            else
            s_roll  = app.A30(3,2)/c_pitch;
            c_roll  = app.A30(3,3)/c_pitch;
            app.roll_angle    = atan2(s_roll,c_roll);
            app.yaw_angle     = atan2(app.A30(2,1)/c_pitch,app.A30(1,1)/c_pitch);
            end
            app.RollpsiEditField.Value  = rad2deg(app.roll_angle);
            app.PitchthetaEditField.Value = rad2deg(app.pitch_angle);
            app.YawphiEditField.Value   = rad2deg(app.yaw_angle);
        end
        %% Calculation End effector position
        function end_effector_cal(app)
            P = app.A30*[0; 0; 0; 1];
            app.xe = P(1,:);
            app.ye = P(2,:);
            app.ze = P(3,:);
            app.XEditField.Value = app.xe;
            app.YEditField.Value = app.ye;
            app.ZEditField.Value = app.ze;
        end
        %% Calculation inverse kinematic
        function [theta1_inv, theta2_inv, theta3_inv] = inverse_kinematic(app,pwx,pwy,pwz)
            pwz         = pwz - app.d1;
            sum         = pwx^2 + pwy^2 + pwz^2;
            if(abs(app.a2-app.a3) < sqrt(sum) && sqrt(sum) < app.a2+app.a3)
                sum_xy      = pwx^2 + pwy^2;
                c_theta3    = (sum-app.a2^2-app.a3^2)/(2*app.a2*app.a3);
                s_theta3    = -sqrt(1-c_theta3^2);
                theta3_inv  = atan2(s_theta3,c_theta3);
                MS          = app.a2^2 + app.a3^2 + 2*app.a2*app.a3*c_theta3;
                c_theta2    = (sqrt(sum_xy)*(app.a2+app.a3*c_theta3)+pwz*app.a3*s_theta3)/MS;
                s_theta2    = (-sqrt(sum_xy)*app.a3*s_theta3+pwz*(app.a2+app.a3*c_theta3))/MS;
                theta2_inv  = atan2(s_theta2,c_theta2);
                theta1_inv  = atan2(pwy,pwx);
                %draw_robot(app,rad2deg(theta1_inv),rad2deg(theta2_inv),rad2deg(theta3_inv));
                app.theta1EditField_inv.Value = rad2deg(theta1_inv);
                app.theta2EditField_inv.Value = rad2deg(theta2_inv);
                app.theta3EditField_inv.Value = rad2deg(theta3_inv);
            else
                msgbox("Wrong Parameter","Warning");
                theta1_inv = 0;
                theta2_inv = 0;
                theta3_inv = 0;
            end
        end
        %% PVA Calculation
        function [p_plan,v_plan,a_plan,t_plan] = PVA_cal(~,p_max,v_max,a_max)
                if p_max < 1
                   p_plan = 0;
                   v_plan = 0;
                   a_plan = 0;
                   t_plan = 0;
                else  
                   % Tính thời gian các pha
                   if(a_max < v_max^2/p_max)
                       a_max = 1.00001*v_max^2/p_max;
                   end
                   T1 = v_max / a_max; % Thời gian tăng tốc
                   T3 = (p_max + v_max^2 / a_max) / v_max; % Thời gian duy trì vận tốc
                   T2 = T3 - v_max / a_max; % Thời gian giảm tốc
                   % Tạo vector thời gian rời rạc
                   t = [0 T1 T2 T3];
                   v = [0 v_max v_max 0]; % Vận tốc tại các mốc
                   % Thời gian chi tiết để tính toán và vẽ
                   %t_plan = linspace(0, T3, 1000);
                   t_plan = 0:0.01:T3;    
                   % Nội suy vận tốc
                   v_plan = interp1(t, v, t_plan, 'linear');
                   % Tính gia tốc a(t): đạo hàm của v(t)
                   a_plan = gradient(v_plan, t_plan);    
                   % Tính vị trí p(t): nguyên hàm của v(t)
                   p_plan = cumtrapz(t_plan, v_plan);
                end
        end
        %% Movement
        function robot_move(app,theta1,theta2,theta3)
            if(theta1 > app.theta1_max || theta1 < app.theta1_min || theta2 > app.theta2_max || theta2 < app.theta2_min || theta3 > app.theta3_max || theta3 < app.theta3_min)
                msgbox("Out of range theta");
            else
            err_theta1 = theta1 - app.theta1_temp;
            err_theta2 = theta2 - app.theta2_temp;
            err_theta3 = theta3 - app.theta3_temp;
            app.theta1_last = app.theta1_temp;
            app.theta2_last = app.theta2_temp;
            app.theta3_last = app.theta3_temp;
            % Calculate PVA
            [p1, v1, acc1, t_plan1] = PVA_cal(app,abs(err_theta1),app.v1_max,app.a1_max);
            [p2, v2, acc2, t_plan2] = PVA_cal(app,abs(err_theta2),app.v2_max,app.a2_max);
            [p3, v3, acc3, t_plan3] = PVA_cal(app,abs(err_theta3),app.v3_max,app.a3_max);
            t_max = max(length(t_plan1),max(length(t_plan2),length(t_plan3)));
            if(t_max == length(t_plan1))
                t_fine = t_plan1;
                p2     = [p2 p2(length(p2))*ones(1,t_max - length(t_plan2))];
                p3     = [p3 p3(length(p3))*ones(1,t_max - length(t_plan3))];
                v2     = [v2 v2(length(v2))*ones(1,t_max - length(t_plan2))];
                v3     = [v3 v3(length(v3))*ones(1,t_max - length(t_plan3))];
                acc2     = [acc2 acc2(length(acc2))*ones(1,t_max - length(t_plan2))];
                acc3     = [acc3 acc3(length(acc3))*ones(1,t_max - length(t_plan3))];
                
            elseif (t_max == length(t_plan2))
                t_fine = t_plan2;
                p1     = [p1 p1(length(p1))*ones(1,t_max - length(t_plan1))];
                p3     = [p3 p3(length(p3))*ones(1,t_max - length(t_plan3))];
                v1     = [v1 v1(length(v1))*ones(1,t_max - length(t_plan1))];
                v3     = [v3 v3(length(v3))*ones(1,t_max - length(t_plan3))];
                acc1     = [acc1 acc1(length(acc1))*ones(1,t_max - length(t_plan1))];
                acc3     = [acc3 acc3(length(acc3))*ones(1,t_max - length(t_plan3))];
            elseif (t_max == length(t_plan3))
                t_fine = t_plan3;
                p1     = [p1 p1(length(p1))*ones(1,t_max - length(t_plan1))];
                p2     = [p2 p2(length(p2))*ones(1,t_max - length(t_plan2))];
                v1     = [v1 v1(length(v1))*ones(1,t_max - length(t_plan1))];
                v2     = [v2 v2(length(v2))*ones(1,t_max - length(t_plan2))];
                acc1     = [acc1 acc1(length(acc1))*ones(1,t_max - length(t_plan1))];
                acc2     = [acc2 acc2(length(acc2))*ones(1,t_max - length(t_plan2))];
            end
            % Determine direction
            if err_theta1 < 0 
                dir1 = -1;
            else
                dir1 = 1;
            end
            if err_theta2 < 0 
                dir2 = -1;
            else
                dir2 = 1;
            end
            
            if err_theta3 < 0 
                dir3 = -1;
            else
                dir3 = 1;
            end
            i = 1;
            % Movement loops
            while (i <= length(t_fine)/10)
                if abs(err_theta1) > app.del_theta1
                    app.theta1_temp = app.theta1_last + dir1*p1(10*i);
                else
                    app.theta1_temp = theta1;
                end
                if abs(err_theta2) > app.del_theta2
                    app.theta2_temp = app.theta2_last + dir2*p2(10*i);
                else
                    app.theta2_temp = theta2;
                end
                 if abs(err_theta3) > app.del_theta3
                    app.theta3_temp = app.theta3_last + dir3*p3(10*i);
                else
                    app.theta3_temp = theta3;
                 end
                 draw_robot(app,app.theta1_temp,app.theta2_temp,app.theta3_temp);
                 % Plotting the PVA result
                 plot(app.UIAxes_theta1,    t_fine(1:10*i), dir1*p1(1:10*i),"Color",'b');
                 plot(app.UIAxes_theta1_d,  t_fine(1:10*i), dir1*v1(1:10*i),"Color",'b');
                 plot(app.UIAxes_theta1_2d, t_fine(1:10*i), dir1*acc1(1:10*i),"Color",'b');
                 plot(app.UIAxes_theta2,    t_fine(1:10*i), dir2*p2(1:10*i),"Color",'b');
                 plot(app.UIAxes_theta2_d,  t_fine(1:10*i), dir2*v2(1:10*i),"Color",'b');
                 plot(app.UIAxes_theta2_2d, t_fine(1:10*i), dir2*acc2(1:10*i),"Color",'b');
                 plot(app.UIAxes_theta3,    t_fine(1:10*i), dir3*p3(1:10*i),"Color",'b');
                 plot(app.UIAxes_theta3_d,  t_fine(1:10*i), dir3*v3(1:10*i),"Color",'b');
                 plot(app.UIAxes_theta3_2d, t_fine(1:10*i), dir3*acc3(1:10*i),"Color",'b');
                 % Delay and update index
                 pause(0.000001);
                 drawnow;
                 %drawnow;
                 i = i + 1;
            end
            end
        end
        %% Creat Workspace data
        function q = creat_data_workspace(app)
            q = zeros(3,1);
            for i = app.theta1_min:10:app.theta1_max
                for j = app.theta2_min:10:app.theta2_max
                    A01 = Trans_Matrix_Calc(i,app.d1,app.a1,app.alpha1);
                    A12 = Trans_Matrix_Calc(j,app.d2,app.a2,app.alpha2);
                    A23 = Trans_Matrix_Calc(app.theta3_min,app.d3,app.a3,app.alpha3);
                    A30 = A01*A12*A23;
                    q = [q A30((1:3),4)];
                end
            end
        
            for i = app.theta1_min:10:app.theta1_max
                for j = app.theta2_min:10:app.theta2_max
                    A01 = Trans_Matrix_Calc(0,app.d1,app.a1,app.alpha1);
                    A12 = Trans_Matrix_Calc(j,app.d2,app.a2,app.alpha2);
                    A23 = Trans_Matrix_Calc(app.theta3_max,app.d3,app.a3,app.alpha3);
                    A30 = A01*A12*A23;
                    q = [q A30((1:3),4)];
                end
            end
            
            for i = app.theta1_min:10:app.theta1_max
                for j = app.theta3_min:10:app.theta3_max
                    A01 = Trans_Matrix_Calc(i,app.d1,app.a1,app.alpha1);
                    A12 = Trans_Matrix_Calc(app.theta2_min,app.d2,app.a2,app.alpha2);
                    A23 = Trans_Matrix_Calc(j,app.d3,app.a3,app.alpha3);
                    A30 = A01*A12*A23;
                    q = [q A30((1:3),4)];
                end
            end
            
            for i = app.theta1_min:10:app.theta1_max
                for j = app.theta3_min:10:app.theta3_max
                    A01 = Trans_Matrix_Calc(i,app.d1,app.a1,app.alpha1);
                    A12 = Trans_Matrix_Calc(app.theta2_max,app.d2,app.a2,app.alpha2);
                    A23 = Trans_Matrix_Calc(j,app.d3,app.a3,app.alpha3);
                    A30 = A01*A12*A23;
                    q = [q A30((1:3),4)];
                end
            end
       end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, d1)
            app.workspace_robot = creat_data_workspace(app);
            app.v1_maxEditField.Value = app.v1_max;
            app.v2_maxEditField.Value = app.v2_max;
            app.v3_maxEditField.Value = app.v3_max;
            app.a1_maxEditField.Value = app.a1_max;
            app.a2_maxEditField.Value = app.a2_max;
            app.a3_maxEditField.Value = app.a3_max;

        end

        % Value changed function: theta_1inputSlider
        function theta_1inputSliderValueChanged(app, event)
            app.Theta1_input = app.theta_1inputSlider.Value;
            app.spinner_theta1.Value = app.Theta1_input;

        end

        % Callback function
        function theta1EditFieldValueChanged(app, event)
            app.Theta1_input = app.spinner_theta1.Value;
            app.theta_1inputSlider.Value = app.Theta1_input;
            
        end

        % Callback function
        function theta2EditFieldValueChanged(app, event)
            app.Theta2_input = app.spinner_theta2.Value;
            app.theta_2inputSlider.Value = app.Theta2_input;
        end

        % Value changed function: theta_3inputSlider
        function theta_3inputSliderValueChanged(app, event)
            app.Theta3_input = app.theta_3inputSlider.Value;
            app.spinner_theta3.Value = app.Theta3_input;

        end

        % Callback function
        function theta3EditFieldValueChanged(app, event)
            app.Theta3_input = app.spinner_theta3.Value;
            app.theta_3inputSlider.Value = app.Theta3_input;

        end

        % Button pushed function: ForwardButton
        function ForwardButtonPushed(app, event)

            app.theta1_temp = app.Theta1_input;
            app.theta2_temp = app.Theta2_input;
            app.theta3_temp = app.Theta3_input;
            draw_robot(app,app.theta1_temp,app.theta2_temp,app.theta3_temp);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            hold(app.UIAxes_2, "off");
            %% Reset theta
            app.Theta1_input = 0;
            app.Theta2_input = 0;
            app.Theta3_input = 0;
            app.theta1_temp = app.Theta1_input;
            app.theta2_temp = app.Theta2_input;
            app.theta3_temp = app.Theta3_input;
            app.theta_1inputSlider.Value = app.Theta1_input;
            app.theta_2inputSlider.Value = app.Theta2_input;
            app.theta_3inputSlider.Value = app.Theta3_input;
            app.spinner_theta1.Value = app.Theta1_input;
            app.spinner_theta2.Value = app.Theta2_input;
            app.spinner_theta3.Value = app.Theta3_input;
            draw_robot(app,0,0,0);
        end

        % Button pushed function: MoveButton
        function MoveButtonPushed(app, event)
            robot_move(app,app.Theta1_input,app.Theta2_input,app.Theta3_input);
        end

        % Button pushed function: InverseButton
        function InverseButtonPushed(app, event)
            app.x_input = app.XEditField_inv.Value;
            app.y_input = app.YEditField_inv.Value;
            app.z_input = app.ZEditField_inv.Value;
            [app.theta1_inv,app.theta2_inv,app.theta3_inv] = inverse_kinematic(app,app.x_input,app.y_input,app.z_input);
            
            end_effector_cal(app);
        end

        % Button pushed function: MoveButton_2
        function MoveButton_2Pushed(app, event)
            robot_move(app,rad2deg(app.theta1_inv),rad2deg(app.theta2_inv),rad2deg(app.theta3_inv));
        end

        % Callback function
        function Theta2SliderValueChanging(app, event)
            app.Theta2_input = app.theta_2inputSlider.Value;
            app.theta2EditField.Value = app.Theta2_input;
        end

        % Value changed function: spinner_theta1
        function spinner_theta1ValueChanged(app, event)
            app.Theta1_input = app.spinner_theta1.Value;
            app.theta_1inputSlider.Value = app.Theta1_input;
        end

        % Value changed function: spinner_theta2
        function spinner_theta2ValueChanged(app, event)
            app.Theta2_input = app.spinner_theta2.Value;
            app.theta_2inputSlider.Value = app.Theta2_input;
        end

        % Value changed function: spinner_theta3
        function spinner_theta3ValueChanged(app, event)
            app.Theta2_input = app.spinner_theta2.Value;
            app.theta_2inputSlider.Value = app.Theta2_input;            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            draw_robot(app,0,0,0);
            
        end

        % Value changed function: theta_2inputSlider
        function theta_2inputSliderValueChanged(app, event)
            app.Theta2_input = app.theta_2inputSlider.Value;
            app.spinner_theta2.Value = app.Theta2_input;
            
        end

        % Button pushed function: InitButton
        function InitButtonPushed(app, event)
            if(app.v1_maxEditField.Value == 0 || app.v2_maxEditField.Value == 0 || app.v3_maxEditField.Value == 0 || app.a1_maxEditField.Value == 0 || app.a2_maxEditField.Value == 0 || app.a3_maxEditField.Value == 0)
                msgbox("Not enough Parameter");
            else
                app.v1_max = app.v1_maxEditField.Value;
                app.v2_max = app.v2_maxEditField.Value;
                app.v3_max = app.v3_maxEditField.Value;
                app.a1_max = app.a1_maxEditField.Value;
                app.a2_max = app.a1_maxEditField.Value;
                app.a3_max = app.a1_maxEditField.Value;
            end
        end

        % Value changed function: WorkplaceCheckbox
        function WorkplaceCheckboxValueChanged(app, event)

            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Robot Coordinate')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.XLim = [-900 900];
            app.UIAxes_2.YLim = [-900 900];
            app.UIAxes_2.ZLim = [-300 1200];
            app.UIAxes_2.XAxisLocation = 'origin';
            app.UIAxes_2.YAxisLocation = 'origin';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.KinematicTab = uitab(app.TabGroup);
            app.KinematicTab.Title = 'Kinematic';
            app.EndeffectorstatePanel = uipanel(app.KinematicTab);
            app.EndeffectorstatePanel.Title = 'End effector state';
            app.XEditFieldLabel = uilabel(app.EndeffectorstatePanel);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.EndeffectorstatePanel, 'numeric');
            app.YEditFieldLabel = uilabel(app.EndeffectorstatePanel);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.EndeffectorstatePanel, 'numeric');
            app.ZEditField = uieditfield(app.EndeffectorstatePanel, 'numeric');
            app.ZEditFieldLabel = uilabel(app.EndeffectorstatePanel);
            app.ZEditFieldLabel.Text = 'Z';
            app.RollpsiEditFieldLabel = uilabel(app.EndeffectorstatePanel);
            app.RollpsiEditFieldLabel.Interpreter = 'latex';
            app.RollpsiEditFieldLabel.Text = 'Roll (\psi)';
            app.RollpsiEditField = uieditfield(app.EndeffectorstatePanel, 'numeric');
            app.PitchthetaEditFieldLabel = uilabel(app.EndeffectorstatePanel);
            app.PitchthetaEditFieldLabel.Interpreter = 'latex';
            app.PitchthetaEditFieldLabel.Text = 'Pitch (\theta)';
            app.PitchthetaEditField = uieditfield(app.EndeffectorstatePanel, 'numeric');
            app.YawphiEditFieldLabel = uilabel(app.EndeffectorstatePanel);
            app.YawphiEditFieldLabel.Interpreter = 'latex';
            app.YawphiEditFieldLabel.Text = 'Yaw (\phi)';
            app.YawphiEditField = uieditfield(app.EndeffectorstatePanel, 'numeric');
            app.ForwardKinematicPanel = uipanel(app.KinematicTab);
            app.ForwardKinematicPanel.Title = 'Forward Kinematic';
            app.theta_1inputSliderLabel = uilabel(app.ForwardKinematicPanel);
            app.theta_1inputSliderLabel.Interpreter = 'latex';
            app.theta_1inputSliderLabel.Text = '$\theta_1$ input';
            app.theta_1inputSlider = uislider(app.ForwardKinematicPanel);
            app.theta_1inputSlider.Limits = [-170 170];
            app.theta_1inputSlider.ValueChangedFcn = createCallbackFcn(app, @theta_1inputSliderValueChanged, true);
            app.theta_2inputSliderLabel = uilabel(app.ForwardKinematicPanel);
            app.theta_2inputSliderLabel.Interpreter = 'latex';
            app.theta_2inputSliderLabel.Text = '$\theta_2$ input';
            app.theta_2inputSlider = uislider(app.ForwardKinematicPanel);
            app.theta_2inputSlider.Limits = [-145 65];
            app.theta_2inputSlider.ValueChangedFcn = createCallbackFcn(app, @theta_2inputSliderValueChanged, true);
            app.theta_2inputSlider.Value = 25;
            app.theta_3inputSliderLabel = uilabel(app.ForwardKinematicPanel);
            app.theta_3inputSliderLabel.Interpreter = 'latex';
            app.theta_3inputSliderLabel.Text = '$\theta_3$ input';
            app.theta_3inputSlider = uislider(app.ForwardKinematicPanel);
            app.theta_3inputSlider.Limits = [-190 70];
            app.theta_3inputSlider.ValueChangedFcn = createCallbackFcn(app, @theta_3inputSliderValueChanged, true);
            app.theta_3inputSlider.Value = 20;
            app.ForwardButton = uibutton(app.ForwardKinematicPanel, 'push');
            app.ForwardButton.ButtonPushedFcn = createCallbackFcn(app, @ForwardButtonPushed, true);
            app.ForwardButton.Text = 'Forward';
            app.MoveButton = uibutton(app.ForwardKinematicPanel, 'push');
            app.MoveButton.ButtonPushedFcn = createCallbackFcn(app, @MoveButtonPushed, true);
            app.MoveButton.Text = 'Move';
            app.spinner_theta1 = uispinner(app.ForwardKinematicPanel);
            app.spinner_theta1.ValueChangedFcn = createCallbackFcn(app, @spinner_theta1ValueChanged, true);
            app.spinner_theta2 = uispinner(app.ForwardKinematicPanel);
            app.spinner_theta2.ValueChangedFcn = createCallbackFcn(app, @spinner_theta2ValueChanged, true);
            app.spinner_theta3 = uispinner(app.ForwardKinematicPanel);
            app.spinner_theta3.ValueChangedFcn = createCallbackFcn(app, @spinner_theta3ValueChanged, true);
            app.RFDisplayPanel = uipanel(app.KinematicTab);
            app.RFDisplayPanel.Title = 'RF Display';
            app.RF0CheckBox = uicheckbox(app.RFDisplayPanel);
            app.RF0CheckBox.Text = 'RF0';
            app.RF1CheckBox = uicheckbox(app.RFDisplayPanel);
            app.RF1CheckBox.Text = 'RF1';
            app.RF2CheckBox = uicheckbox(app.RFDisplayPanel);
            app.RF2CheckBox.Text = 'RF2';
            app.RF3CheckBox = uicheckbox(app.RFDisplayPanel);
            app.RF3CheckBox.Text = 'RF3';
            app.WorkplaceCheckbox = uicheckbox(app.RFDisplayPanel);
            app.WorkplaceCheckbox.ValueChangedFcn = createCallbackFcn(app, @WorkplaceCheckboxValueChanged, true);
            app.WorkplaceCheckbox.Text = 'Show the workplace';
            app.ResetButton = uibutton(app.KinematicTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.InverseKinematicPanel = uipanel(app.KinematicTab);
            app.InverseKinematicPanel.Title = 'Inverse Kinematic';
            app.XEditField_2Label = uilabel(app.InverseKinematicPanel);
            app.XEditField_2Label.Text = 'X';
            app.XEditField_inv = uieditfield(app.InverseKinematicPanel, 'numeric');
            app.YEditField_2Label = uilabel(app.InverseKinematicPanel);
            app.YEditField_2Label.Text = 'Y';
            app.YEditField_inv = uieditfield(app.InverseKinematicPanel, 'numeric');
            app.ZEditField_inv = uieditfield(app.InverseKinematicPanel, 'numeric');
            app.ZEditField_2Label = uilabel(app.InverseKinematicPanel);
            app.ZEditField_2Label.Text = 'Z';
            app.InverseButton = uibutton(app.InverseKinematicPanel, 'push');
            app.InverseButton.ButtonPushedFcn = createCallbackFcn(app, @InverseButtonPushed, true);
            app.InverseButton.Text = 'Inverse';
            app.XEditField_2Label_2 = uilabel(app.InverseKinematicPanel);
            app.XEditField_2Label_2.Interpreter = 'latex';
            app.XEditField_2Label_2.Text = '$\theta_1$ inv';
            app.theta1EditField_inv = uieditfield(app.InverseKinematicPanel, 'numeric');
            app.XEditField_2Label_3 = uilabel(app.InverseKinematicPanel);
            app.XEditField_2Label_3.Interpreter = 'latex';
            app.XEditField_2Label_3.Text = '$\theta_2$ inv';
            app.theta2EditField_inv = uieditfield(app.InverseKinematicPanel, 'numeric');
            app.XEditField_2Label_4 = uilabel(app.InverseKinematicPanel);
            app.XEditField_2Label_4.Interpreter = 'latex';
            app.XEditField_2Label_4.Text = '$\theta_3$ inv';
            app.theta3EditField_inv = uieditfield(app.InverseKinematicPanel, 'numeric');
            app.MoveButton_2 = uibutton(app.InverseKinematicPanel, 'push');
            app.MoveButton_2.ButtonPushedFcn = createCallbackFcn(app, @MoveButton_2Pushed, true);
            app.MoveButton_2.Text = 'Move';
            app.StartButton = uibutton(app.KinematicTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.JointstatePanel = uipanel(app.KinematicTab);
            app.JointstatePanel.Title = 'Joint state';
            app.theta_1Label = uilabel(app.JointstatePanel);
            app.theta_1Label.Interpreter = 'latex';
            app.theta_1Label.Text = '$\theta_1$';
            app.theta1EditField = uieditfield(app.JointstatePanel, 'numeric');
            app.YEditField_2Label_2 = uilabel(app.JointstatePanel);
            app.YEditField_2Label_2.Interpreter = 'latex';
            app.YEditField_2Label_2.Text = '$\theta_2$';
            app.theta2EditField = uieditfield(app.JointstatePanel, 'numeric');
            app.theta3EditField = uieditfield(app.JointstatePanel, 'numeric');
            app.ZEditField_2Label_2 = uilabel(app.JointstatePanel);
            app.ZEditField_2Label_2.Interpreter = 'latex';
            app.ZEditField_2Label_2.Text = '$\theta_3$';
            app.PVAParameterPanel = uipanel(app.KinematicTab);
            app.PVAParameterPanel.Title = 'PVA Parameter';
            app.v_maxLabel = uilabel(app.PVAParameterPanel);
            app.v_maxLabel.Interpreter = 'latex';
            app.v_maxLabel.Text = '$v1_{max}$';
            app.v1_maxEditField = uieditfield(app.PVAParameterPanel, 'numeric');
            app.a1_maxEditFieldLabel = uilabel(app.PVAParameterPanel);
            app.a1_maxEditFieldLabel.Interpreter = 'latex';
            app.a1_maxEditFieldLabel.Text = '$a1_{max}$';
            app.a1_maxEditField = uieditfield(app.PVAParameterPanel, 'numeric');
            app.InitButton = uibutton(app.PVAParameterPanel, 'push');
            app.InitButton.ButtonPushedFcn = createCallbackFcn(app, @InitButtonPushed, true);
            app.InitButton.Text = 'Init';
            app.v2_maxEditFieldLabel = uilabel(app.PVAParameterPanel);
            app.v2_maxEditFieldLabel.Interpreter = 'latex';
            app.v2_maxEditFieldLabel.Text = '$v2_{max}$';
            app.v2_maxEditField = uieditfield(app.PVAParameterPanel, 'numeric');
            app.a2_maxEditFieldLabel = uilabel(app.PVAParameterPanel);
            app.a2_maxEditFieldLabel.Interpreter = 'latex';
            app.a2_maxEditFieldLabel.Text = '$a2_{max}$';
            app.a2_maxEditField = uieditfield(app.PVAParameterPanel, 'numeric');
            app.a3_maxEditFieldLabel = uilabel(app.PVAParameterPanel);
            app.a3_maxEditFieldLabel.Interpreter = 'latex';
            app.a3_maxEditFieldLabel.Text = '$a3_{max}$';
            app.a3_maxEditField = uieditfield(app.PVAParameterPanel, 'numeric');
            app.v3_maxEditFieldLabel = uilabel(app.PVAParameterPanel);
            app.v3_maxEditFieldLabel.Interpreter = 'latex';
            app.v3_maxEditFieldLabel.Text = '$v3_{max}$';
            app.v3_maxEditField = uieditfield(app.PVAParameterPanel, 'numeric');
            app.TrajectoryPlaningTab = uitab(app.TabGroup);
            app.TrajectoryPlaningTab.Title = 'Trajectory Planing';
            app.UIAxes_theta1 = uiaxes(app.TrajectoryPlaningTab);
            title(app.UIAxes_theta1, 'Theta1')
            xlabel(app.UIAxes_theta1, 'Time(s)')
            ylabel(app.UIAxes_theta1, 'Angle (degree)')
            zlabel(app.UIAxes_theta1, 'Z')
            app.UIAxes_theta1_d = uiaxes(app.TrajectoryPlaningTab);
            title(app.UIAxes_theta1_d, 'Theta1_dot')
            xlabel(app.UIAxes_theta1_d, 'Time (s)')
            ylabel(app.UIAxes_theta1_d, 'Degree/s')
            zlabel(app.UIAxes_theta1_d, 'Z')
            app.UIAxes_theta1_2d = uiaxes(app.TrajectoryPlaningTab);
            title(app.UIAxes_theta1_2d, 'Theta1_2dot')
            xlabel(app.UIAxes_theta1_2d, 'Time (s)')
            ylabel(app.UIAxes_theta1_2d, 'Degree/s^2')
            zlabel(app.UIAxes_theta1_2d, 'Z')
            app.UIAxes_theta2 = uiaxes(app.TrajectoryPlaningTab);
            title(app.UIAxes_theta2, 'Theta2')
            xlabel(app.UIAxes_theta2, 'Time (s)')
            ylabel(app.UIAxes_theta2, 'Angle (Degree)')
            zlabel(app.UIAxes_theta2, 'Z')
            app.UIAxes_theta2_d = uiaxes(app.TrajectoryPlaningTab);
            title(app.UIAxes_theta2_d, 'Theta2_dot')
            xlabel(app.UIAxes_theta2_d, 'Time (s)')
            ylabel(app.UIAxes_theta2_d, 'Degree/s')
            zlabel(app.UIAxes_theta2_d, 'Z')
            app.UIAxes_theta2_2d = uiaxes(app.TrajectoryPlaningTab);
            title(app.UIAxes_theta2_2d, 'Theta2_2dot')
            xlabel(app.UIAxes_theta2_2d, 'Time (s)')
            ylabel(app.UIAxes_theta2_2d, 'Degree/s^2')
            zlabel(app.UIAxes_theta2_2d, 'Z')
            app.UIAxes_theta3 = uiaxes(app.TrajectoryPlaningTab);
            title(app.UIAxes_theta3, 'Theta3')
            xlabel(app.UIAxes_theta3, 'Time (s)')
            ylabel(app.UIAxes_theta3, 'Degree')
            zlabel(app.UIAxes_theta3, 'Z')
            app.UIAxes_theta3_d = uiaxes(app.TrajectoryPlaningTab);
            title(app.UIAxes_theta3_d, 'Theta3_dot')
            xlabel(app.UIAxes_theta3_d, 'X')
            ylabel(app.UIAxes_theta3_d, 'Y')
            zlabel(app.UIAxes_theta3_d, 'Z')
            app.UIAxes_theta3_2d = uiaxes(app.TrajectoryPlaningTab);
            title(app.UIAxes_theta3_2d, 'Theta3_2dot')
            xlabel(app.UIAxes_theta3_2d, 'X')
            ylabel(app.UIAxes_theta3_2d, 'Y')
            zlabel(app.UIAxes_theta3_2d, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BTL_Robot_2112447_Yaskawa(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end